classdef app_3ch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ChannelDropDown              matlab.ui.control.DropDown
        ChannelDropDownLabel         matlab.ui.control.Label
        Spinner_10Label              matlab.ui.control.Label
        Slope41ratioSpinnerLabel     matlab.ui.control.Label
        Spinner_20                   matlab.ui.control.Spinner
        Spinner_10                   matlab.ui.control.Spinner
        Slope41ratioSpinner          matlab.ui.control.Spinner
        Spinner_9Label               matlab.ui.control.Label
        Threshold3dBFsSpinnerLabel   matlab.ui.control.Label
        Spinner_19                   matlab.ui.control.Spinner
        Spinner_9                    matlab.ui.control.Spinner
        Threshold3dBFsSpinner        matlab.ui.control.Spinner
        Spinner_8Label               matlab.ui.control.Label
        Slope31ratioSpinnerLabel     matlab.ui.control.Label
        Spinner_18                   matlab.ui.control.Spinner
        Spinner_8                    matlab.ui.control.Spinner
        Slope31ratioSpinner          matlab.ui.control.Spinner
        Spinner_7Label               matlab.ui.control.Label
        Threshold2dBFsSpinnerLabel   matlab.ui.control.Label
        Spinner_17                   matlab.ui.control.Spinner
        Spinner_7                    matlab.ui.control.Spinner
        Threshold2dBFsSpinner        matlab.ui.control.Spinner
        Spinner_6Label               matlab.ui.control.Label
        Slope21ratioSpinnerLabel     matlab.ui.control.Label
        Spinner_16                   matlab.ui.control.Spinner
        Spinner_6                    matlab.ui.control.Spinner
        Slope21ratioSpinner          matlab.ui.control.Spinner
        Spinner_5Label               matlab.ui.control.Label
        Threshold1dBFsSpinnerLabel   matlab.ui.control.Label
        Spinner_15                   matlab.ui.control.Spinner
        Spinner_5                    matlab.ui.control.Spinner
        Threshold1dBFsSpinner        matlab.ui.control.Spinner
        Spinner_4Label               matlab.ui.control.Label
        Slope11ratioSpinnerLabel     matlab.ui.control.Label
        Spinner_14                   matlab.ui.control.Spinner
        Spinner_4                    matlab.ui.control.Spinner
        Slope11ratioSpinner          matlab.ui.control.Spinner
        Spinner_3Label               matlab.ui.control.Label
        Threshold0dBFsSpinnerLabel   matlab.ui.control.Label
        Spinner_13                   matlab.ui.control.Spinner
        Spinner_3                    matlab.ui.control.Spinner
        Threshold0dBFsSpinner        matlab.ui.control.Spinner
        Spinner_2Label               matlab.ui.control.Label
        Slope01ratioSpinner_3Label   matlab.ui.control.Label
        Spinner_12                   matlab.ui.control.Spinner
        Spinner_2                    matlab.ui.control.Spinner
        Slope01ratioSpinner_3        matlab.ui.control.Spinner
        Spinner_21                   matlab.ui.control.Spinner
        Spinner_11                   matlab.ui.control.Spinner
        SpinnerLabel                 matlab.ui.control.Label
        Spinner                      matlab.ui.control.Spinner
        CrossoverfrequencyHzSpinner  matlab.ui.control.Spinner
        CrossoverfrequencyHzSpinnerLabel  matlab.ui.control.Label
        Channel3Label                matlab.ui.control.Label
        Channel2Label                matlab.ui.control.Label
        Channel1Label                matlab.ui.control.Label
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data % Description
    end   
    
    methods (Access = public)
        
        function ioplot(app)
            x =app.data.(["ch"+ app.ChannelDropDown.Value]).x;
            y =app.data.(["ch"+ app.ChannelDropDown.Value]).y;
            plot(app.UIAxes,x,y);
            plot(app.UIAxes_3,x,y-x);
        end
        
        function [x,y] = iocal(~, th,slope)
            x=linspace(-127,0,128);
            y=zeros(1,128);
            index = 2;
            y_value = th(1);
            for i =th(1):1:0
                if i >= th(index) && th(index)~=0
                    index = index + 1;
                end
                y(1,i+128) = y_value;
                y_value = y_value + slope(index);
            end
            index = 1;
            y_value = th(1);
            for i =th(1):-1:-127
                y(1,i+128) = y_value;
                y_value = y_value - slope(index);
            end
        end
        
        function plot_3d(app)
            x = repelem(app.data.freq,128,1);
            y = [app.data.ch1.x;app.data.ch1.x;app.data.ch2.x;app.data.ch2.x;app.data.ch3.x;app.data.ch3.x];
            z = [app.data.ch1.y;app.data.ch1.y;app.data.ch2.y;app.data.ch2.y;app.data.ch3.y;app.data.ch3.y];
            surf(app.UIAxes_2,y,x',z);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.data.freq = [0, app.Spinner.Value, app.Spinner.Value+1, app.Spinner_11.Value,app.Spinner_11.Value+1, 12000];
            app.data.ch1.th = [app.Threshold0dBFsSpinner.Value,app.Threshold1dBFsSpinner.Value,app.Threshold2dBFsSpinner.Value,app.Threshold3dBFsSpinner.Value,0]; 
            app.data.ch1.slope = [app.Slope01ratioSpinner_3.Value,app.Slope11ratioSpinner.Value,app.Slope21ratioSpinner.Value,app.Slope31ratioSpinner.Value,app.Slope41ratioSpinner.Value];
            [app.data.ch1.x, app.data.ch1.y] = iocal(app, app.data.ch1.th, app.data.ch1.slope);
            app.data.ch2.th = [app.Spinner_3.Value,app.Spinner_5.Value,app.Spinner_7.Value,app.Spinner_9.Value,0]; 
            app.data.ch2.slope = [app.Spinner_2.Value,app.Spinner_4.Value,app.Spinner_6.Value,app.Spinner_8.Value,app.Spinner_10.Value];
            [app.data.ch2.x, app.data.ch2.y] = iocal(app, app.data.ch2.th, app.data.ch2.slope);
            app.data.ch3.th = [app.Spinner_13.Value,app.Spinner_15.Value,app.Spinner_17.Value,app.Spinner_19.Value,0]; 
            app.data.ch3.slope = [app.Spinner_12.Value,app.Spinner_14.Value,app.Spinner_16.Value,app.Spinner_18.Value,app.Spinner_20.Value];
            [app.data.ch3.x, app.data.ch3.y] = iocal(app, app.data.ch3.th, app.data.ch3.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            ioplot(app);
        end

        % Value changed function: Slope01ratioSpinner_3
        function Slope01ratioSpinner_3ValueChanged(app, event)
            app.data.ch1.slope(1) = app.Slope01ratioSpinner_3.Value;
            [app.data.ch1.x, app.data.ch1.y] = iocal(app, app.data.ch1.th, app.data.ch1.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Threshold0dBFsSpinner
        function Threshold0dBFsSpinnerValueChanged(app, event)
            app.data.ch1.th(1) = app.Threshold0dBFsSpinner.Value;
            [app.data.ch1.x, app.data.ch1.y] = iocal(app, app.data.ch1.th, app.data.ch1.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Slope11ratioSpinner
        function Slope11ratioSpinnerValueChanged(app, event)
            app.data.ch1.slope(2) = app.Slope11ratioSpinner.Value;
            [app.data.ch1.x, app.data.ch1.y] = iocal(app, app.data.ch1.th, app.data.ch1.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Threshold1dBFsSpinner
        function Threshold1dBFsSpinnerValueChanged(app, event)
            app.data.ch1.th(2) = app.Threshold1dBFsSpinner.Value;
            [app.data.ch1.x, app.data.ch1.y] = iocal(app, app.data.ch1.th, app.data.ch1.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Slope21ratioSpinner
        function Slope21ratioSpinnerValueChanged(app, event)
            app.data.ch1.slope(3) = app.Slope21ratioSpinner.Value;
            [app.data.ch1.x, app.data.ch1.y] = iocal(app, app.data.ch1.th, app.data.ch1.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Threshold2dBFsSpinner
        function Threshold2dBFsSpinnerValueChanged(app, event)
            app.data.ch1.th(3) = app.Threshold2dBFsSpinner.Value;
            [app.data.ch1.x, app.data.ch1.y] = iocal(app, app.data.ch1.th, app.data.ch1.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Slope31ratioSpinner
        function Slope31ratioSpinnerValueChanged(app, event)
            app.data.ch1.slope(4) = app.Slope31ratioSpinner.Value;
            [app.data.ch1.x, app.data.ch1.y] = iocal(app, app.data.ch1.th, app.data.ch1.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Threshold3dBFsSpinner
        function Threshold3dBFsSpinnerValueChanged(app, event)
            app.data.ch1.th(4) = app.Threshold3dBFsSpinner.Value;
            [app.data.ch1.x, app.data.ch1.y] = iocal(app, app.data.ch1.th, app.data.ch1.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Slope41ratioSpinner
        function Slope41ratioSpinnerValueChanged(app, event)
            app.data.ch1.slope(5) = app.Slope41ratioSpinner.Value;
            [app.data.ch1.x, app.data.ch1.y] = iocal(app, app.data.ch1.th, app.data.ch1.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            app.data.ch2.slope(1) = app.Spinner_2.Value;
            [app.data.ch2.x, app.data.ch2.y] = iocal(app, app.data.ch2.th, app.data.ch2.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_3
        function Spinner_3ValueChanged(app, event)
            app.data.ch2.th(1) = app.Spinner_3.Value;
            [app.data.ch2.x, app.data.ch2.y] = iocal(app, app.data.ch2.th, app.data.ch2.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_4
        function Spinner_4ValueChanged(app, event)
            app.data.ch2.slope(2) = app.Spinner_4.Value;
            [app.data.ch2.x, app.data.ch2.y] = iocal(app, app.data.ch2.th, app.data.ch2.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_5
        function Spinner_5ValueChanged(app, event)
            app.data.ch2.th(2) = app.Spinner_5.Value;
            [app.data.ch2.x, app.data.ch2.y] = iocal(app, app.data.ch2.th, app.data.ch2.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_6
        function Spinner_6ValueChanged(app, event)
            app.data.ch2.slope(3) = app.Spinner_6.Value;
            [app.data.ch2.x, app.data.ch2.y] = iocal(app, app.data.ch2.th, app.data.ch2.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_7
        function Spinner_7ValueChanged(app, event)
            app.data.ch2.th(3) = app.Spinner_7.Value;
            [app.data.ch2.x, app.data.ch2.y] = iocal(app, app.data.ch2.th, app.data.ch2.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_8
        function Spinner_8ValueChanged(app, event)
            app.data.ch2.slope(4) = app.Spinner_8.Value;
            [app.data.ch2.x, app.data.ch2.y] = iocal(app, app.data.ch2.th, app.data.ch2.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_9
        function Spinner_9ValueChanged(app, event)
            app.data.ch2.th(4) = app.Spinner_9.Value;
            [app.data.ch2.x, app.data.ch2.y] = iocal(app, app.data.ch2.th, app.data.ch2.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_10
        function Spinner_10ValueChanged(app, event)
            app.data.ch2.slope(5) = app.Spinner_10.Value;
            [app.data.ch2.x, app.data.ch2.y] = iocal(app, app.data.ch2.th, app.data.ch2.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: CrossoverfrequencyHzSpinner
        function CrossoverfrequencyHzSpinnerValueChanged(app, event)
            app.CrossoverfrequencyHzSpinner.Value = 0;            
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.Spinner_11.Limits = [app.Spinner.Value+1, app.Spinner_21.Value-1];
            app.data.freq = [0, app.Spinner.Value, app.Spinner.Value+1, app.Spinner_11.Value,app.Spinner_11.Value+1, 12000];
            plot_3d(app);
        end

        % Value changed function: Spinner_11
        function Spinner_11ValueChanged(app, event)
            app.Spinner.Limits = [1, app.Spinner_11.Value-1];
            app.Spinner_21.Limits = [app.Spinner_11.Value+1, 12000];
            app.data.freq = [0, app.Spinner.Value, app.Spinner.Value+1, app.Spinner_11.Value,app.Spinner_11.Value+1, 12000];
            plot_3d(app);
        end

        % Value changed function: Spinner_21
        function Spinner_21ValueChanged(app, event)
            app.Spinner_11.Limits = [app.Spinner.Value+1, app.Spinner_21.Value-1];
            app.data.freq = [0, app.Spinner.Value, app.Spinner.Value+1, app.Spinner_11.Value,app.Spinner_11.Value+1, 12000];
            plot_3d(app);
        end

        % Value changed function: Spinner_12
        function Spinner_12ValueChanged(app, event)
            app.data.ch3.slope(1) = app.Spinner_12.Value;
            [app.data.ch3.x, app.data.ch3.y] = iocal(app, app.data.ch3.th, app.data.ch3.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_13
        function Spinner_13ValueChanged(app, event)
            app.data.ch3.th(1) = app.Spinner_13.Value;
            [app.data.ch3.x, app.data.ch3.y] = iocal(app, app.data.ch3.th, app.data.ch3.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_14
        function Spinner_14ValueChanged(app, event)
            app.data.ch3.slope(2) = app.Spinner_14.Value;
            [app.data.ch3.x, app.data.ch3.y] = iocal(app, app.data.ch3.th, app.data.ch3.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_15
        function Spinner_15ValueChanged(app, event)
            app.data.ch3.th(2) = app.Spinner_15.Value;
            [app.data.ch3.x, app.data.ch3.y] = iocal(app, app.data.ch3.th, app.data.ch3.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_16
        function Spinner_16ValueChanged(app, event)
            app.data.ch3.slope(3) = app.Spinner_16.Value;
            [app.data.ch3.x, app.data.ch3.y] = iocal(app, app.data.ch3.th, app.data.ch3.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_17
        function Spinner_17ValueChanged(app, event)
            app.data.ch3.th(3) = app.Spinner_17.Value;
            [app.data.ch3.x, app.data.ch3.y] = iocal(app, app.data.ch3.th, app.data.ch3.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_18
        function Spinner_18ValueChanged(app, event)
            app.data.ch3.slope(4) = app.Spinner_18.Value;
            [app.data.ch3.x, app.data.ch3.y] = iocal(app, app.data.ch3.th, app.data.ch3.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_19
        function Spinner_19ValueChanged(app, event)
            app.data.ch3.th(4) = app.Spinner_19.Value;
            [app.data.ch3.x, app.data.ch3.y] = iocal(app, app.data.ch3.th, app.data.ch3.slope);
            ioplot(app);
            plot_3d(app);
        end

        % Value changed function: Spinner_20
        function Spinner_20ValueChanged(app, event)
            app.data.ch3.slope(5) = app.Spinner_20.Value;
            [app.data.ch3.x, app.data.ch3.y] = iocal(app, app.data.ch3.th, app.data.ch3.slope);
            ioplot(app);
            plot_3d(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input/Output')
            xlabel(app.UIAxes, 'Input dBFs')
            ylabel(app.UIAxes, 'Output dBFs')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-127 0];
            app.UIAxes.YLim = [-127 0];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Input/Output')
            xlabel(app.UIAxes_2, 'Input dBFs')
            ylabel(app.UIAxes_2, 'Frequency (Hz)')
            zlabel(app.UIAxes_2, 'Output dBFs')
            app.UIAxes_2.XLim = [-127 0];
            app.UIAxes_2.YLim = [0 12000];
            app.UIAxes_2.ZLim = [-127 0];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Input/Gain')
            xlabel(app.UIAxes_3, 'Input dBFs')
            ylabel(app.UIAxes_3, 'Gain dB')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XLim = [-127 0];
            app.UIAxes_3.YLim = [-40 40];
            app.Channel1Label = uilabel(app.UIFigure);
            app.Channel1Label.Text = 'Channel 1';
            app.Channel2Label = uilabel(app.UIFigure);
            app.Channel2Label.Text = 'Channel 2';
            app.Channel3Label = uilabel(app.UIFigure);
            app.Channel3Label.Text = 'Channel 3';
            app.CrossoverfrequencyHzSpinnerLabel = uilabel(app.UIFigure);
            app.CrossoverfrequencyHzSpinnerLabel.Text = 'Crossover frequency (Hz)';
            app.CrossoverfrequencyHzSpinner = uispinner(app.UIFigure);
            app.CrossoverfrequencyHzSpinner.Limits = [0 1];
            app.CrossoverfrequencyHzSpinner.ValueChangedFcn = createCallbackFcn(app, @CrossoverfrequencyHzSpinnerValueChanged, true);
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [0 4000];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 4000;
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = '';
            app.Spinner_11 = uispinner(app.UIFigure);
            app.Spinner_11.Limits = [4000 8000];
            app.Spinner_11.ValueChangedFcn = createCallbackFcn(app, @Spinner_11ValueChanged, true);
            app.Spinner_11.Value = 8000;
            app.Spinner_21 = uispinner(app.UIFigure);
            app.Spinner_21.Limits = [8000 12000];
            app.Spinner_21.ValueDisplayFormat = '%.0f';
            app.Spinner_21.ValueChangedFcn = createCallbackFcn(app, @Spinner_21ValueChanged, true);
            app.Spinner_21.Value = 12000;
            app.Slope01ratioSpinner_3 = uispinner(app.UIFigure);
            app.Slope01ratioSpinner_3.Step = 0.1;
            app.Slope01ratioSpinner_3.Limits = [0 8];
            app.Slope01ratioSpinner_3.ValueChangedFcn = createCallbackFcn(app, @Slope01ratioSpinner_3ValueChanged, true);
            app.Slope01ratioSpinner_3.Value = 1.5;
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.Step = 0.1;
            app.Spinner_2.Limits = [0 8];
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Value = 1;
            app.Spinner_12 = uispinner(app.UIFigure);
            app.Spinner_12.Step = 0.1;
            app.Spinner_12.Limits = [0 8];
            app.Spinner_12.ValueChangedFcn = createCallbackFcn(app, @Spinner_12ValueChanged, true);
            app.Spinner_12.Value = 1;
            app.Slope01ratioSpinner_3Label = uilabel(app.UIFigure);
            app.Slope01ratioSpinner_3Label.Text = 'Slope 0 (1/ratio)';
            app.Spinner_2Label = uilabel(app.UIFigure);
            app.Spinner_2Label.Text = '';
            app.Threshold0dBFsSpinner = uispinner(app.UIFigure);
            app.Threshold0dBFsSpinner.Limits = [-127 0];
            app.Threshold0dBFsSpinner.ValueChangedFcn = createCallbackFcn(app, @Threshold0dBFsSpinnerValueChanged, true);
            app.Threshold0dBFsSpinner.Value = -70;
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Spinner_3.Limits = [-127 0];
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_3ValueChanged, true);
            app.Spinner_3.Value = -70;
            app.Spinner_13 = uispinner(app.UIFigure);
            app.Spinner_13.Limits = [-127 0];
            app.Spinner_13.ValueChangedFcn = createCallbackFcn(app, @Spinner_13ValueChanged, true);
            app.Spinner_13.Value = -70;
            app.Threshold0dBFsSpinnerLabel = uilabel(app.UIFigure);
            app.Threshold0dBFsSpinnerLabel.Text = 'Threshold 0 (dBFs)';
            app.Spinner_3Label = uilabel(app.UIFigure);
            app.Spinner_3Label.Text = '';
            app.Slope11ratioSpinner = uispinner(app.UIFigure);
            app.Slope11ratioSpinner.Step = 0.1;
            app.Slope11ratioSpinner.Limits = [0 8];
            app.Slope11ratioSpinner.ValueChangedFcn = createCallbackFcn(app, @Slope11ratioSpinnerValueChanged, true);
            app.Slope11ratioSpinner.Value = 1;
            app.Spinner_4 = uispinner(app.UIFigure);
            app.Spinner_4.Step = 0.1;
            app.Spinner_4.Limits = [0 8];
            app.Spinner_4.ValueChangedFcn = createCallbackFcn(app, @Spinner_4ValueChanged, true);
            app.Spinner_4.Value = 1;
            app.Spinner_14 = uispinner(app.UIFigure);
            app.Spinner_14.Step = 0.1;
            app.Spinner_14.Limits = [0 8];
            app.Spinner_14.ValueChangedFcn = createCallbackFcn(app, @Spinner_14ValueChanged, true);
            app.Spinner_14.Value = 1;
            app.Slope11ratioSpinnerLabel = uilabel(app.UIFigure);
            app.Slope11ratioSpinnerLabel.Text = 'Slope 1 (1/ratio)';
            app.Spinner_4Label = uilabel(app.UIFigure);
            app.Spinner_4Label.Text = '';
            app.Threshold1dBFsSpinner = uispinner(app.UIFigure);
            app.Threshold1dBFsSpinner.Limits = [-127 0];
            app.Threshold1dBFsSpinner.ValueChangedFcn = createCallbackFcn(app, @Threshold1dBFsSpinnerValueChanged, true);
            app.Threshold1dBFsSpinner.Value = -40;
            app.Spinner_5 = uispinner(app.UIFigure);
            app.Spinner_5.Limits = [-127 0];
            app.Spinner_5.ValueChangedFcn = createCallbackFcn(app, @Spinner_5ValueChanged, true);
            app.Spinner_5.Value = -40;
            app.Spinner_15 = uispinner(app.UIFigure);
            app.Spinner_15.Limits = [-127 0];
            app.Spinner_15.ValueChangedFcn = createCallbackFcn(app, @Spinner_15ValueChanged, true);
            app.Spinner_15.Value = -40;
            app.Threshold1dBFsSpinnerLabel = uilabel(app.UIFigure);
            app.Threshold1dBFsSpinnerLabel.Text = 'Threshold 1 (dBFs)';
            app.Spinner_5Label = uilabel(app.UIFigure);
            app.Spinner_5Label.Text = '';
            app.Slope21ratioSpinner = uispinner(app.UIFigure);
            app.Slope21ratioSpinner.Step = 0.1;
            app.Slope21ratioSpinner.Limits = [0 8];
            app.Slope21ratioSpinner.ValueChangedFcn = createCallbackFcn(app, @Slope21ratioSpinnerValueChanged, true);
            app.Slope21ratioSpinner.Value = 0.5;
            app.Spinner_6 = uispinner(app.UIFigure);
            app.Spinner_6.Step = 0.1;
            app.Spinner_6.Limits = [0 8];
            app.Spinner_6.ValueChangedFcn = createCallbackFcn(app, @Spinner_6ValueChanged, true);
            app.Spinner_6.Value = 1;
            app.Spinner_16 = uispinner(app.UIFigure);
            app.Spinner_16.Step = 0.1;
            app.Spinner_16.Limits = [0 8];
            app.Spinner_16.ValueChangedFcn = createCallbackFcn(app, @Spinner_16ValueChanged, true);
            app.Spinner_16.Value = 1;
            app.Slope21ratioSpinnerLabel = uilabel(app.UIFigure);
            app.Slope21ratioSpinnerLabel.Text = 'Slope 2 (1/ratio)';
            app.Spinner_6Label = uilabel(app.UIFigure);
            app.Spinner_6Label.Text = '';
            app.Threshold2dBFsSpinner = uispinner(app.UIFigure);
            app.Threshold2dBFsSpinner.Limits = [-127 0];
            app.Threshold2dBFsSpinner.ValueChangedFcn = createCallbackFcn(app, @Threshold2dBFsSpinnerValueChanged, true);
            app.Threshold2dBFsSpinner.Value = -30;
            app.Spinner_7 = uispinner(app.UIFigure);
            app.Spinner_7.Limits = [-127 0];
            app.Spinner_7.ValueChangedFcn = createCallbackFcn(app, @Spinner_7ValueChanged, true);
            app.Spinner_7.Value = -30;
            app.Spinner_17 = uispinner(app.UIFigure);
            app.Spinner_17.Limits = [-127 0];
            app.Spinner_17.ValueChangedFcn = createCallbackFcn(app, @Spinner_17ValueChanged, true);
            app.Spinner_17.Value = -30;
            app.Threshold2dBFsSpinnerLabel = uilabel(app.UIFigure);
            app.Threshold2dBFsSpinnerLabel.Text = 'Threshold 2 (dBFs)';
            app.Spinner_7Label = uilabel(app.UIFigure);
            app.Spinner_7Label.Text = '';
            app.Slope31ratioSpinner = uispinner(app.UIFigure);
            app.Slope31ratioSpinner.Step = 0.1;
            app.Slope31ratioSpinner.Limits = [0 8];
            app.Slope31ratioSpinner.ValueChangedFcn = createCallbackFcn(app, @Slope31ratioSpinnerValueChanged, true);
            app.Slope31ratioSpinner.Value = 1;
            app.Spinner_8 = uispinner(app.UIFigure);
            app.Spinner_8.Step = 0.1;
            app.Spinner_8.Limits = [0 8];
            app.Spinner_8.ValueChangedFcn = createCallbackFcn(app, @Spinner_8ValueChanged, true);
            app.Spinner_8.Value = 1;
            app.Spinner_18 = uispinner(app.UIFigure);
            app.Spinner_18.Step = 0.1;
            app.Spinner_18.Limits = [0 8];
            app.Spinner_18.ValueChangedFcn = createCallbackFcn(app, @Spinner_18ValueChanged, true);
            app.Spinner_18.Value = 1;
            app.Slope31ratioSpinnerLabel = uilabel(app.UIFigure);
            app.Slope31ratioSpinnerLabel.Text = 'Slope 3 (1/ratio)';
            app.Spinner_8Label = uilabel(app.UIFigure);
            app.Spinner_8Label.Text = '';
            app.Threshold3dBFsSpinner = uispinner(app.UIFigure);
            app.Threshold3dBFsSpinner.Limits = [-127 0];
            app.Threshold3dBFsSpinner.ValueChangedFcn = createCallbackFcn(app, @Threshold3dBFsSpinnerValueChanged, true);
            app.Threshold3dBFsSpinner.Value = -10;
            app.Spinner_9 = uispinner(app.UIFigure);
            app.Spinner_9.Limits = [-127 0];
            app.Spinner_9.ValueChangedFcn = createCallbackFcn(app, @Spinner_9ValueChanged, true);
            app.Spinner_9.Value = -10;
            app.Spinner_19 = uispinner(app.UIFigure);
            app.Spinner_19.Limits = [-127 0];
            app.Spinner_19.ValueChangedFcn = createCallbackFcn(app, @Spinner_19ValueChanged, true);
            app.Spinner_19.Value = -10;
            app.Threshold3dBFsSpinnerLabel = uilabel(app.UIFigure);
            app.Threshold3dBFsSpinnerLabel.Text = 'Threshold 3 (dBFs)';
            app.Spinner_9Label = uilabel(app.UIFigure);
            app.Spinner_9Label.Text = '';
            app.Slope41ratioSpinner = uispinner(app.UIFigure);
            app.Slope41ratioSpinner.Step = 0.1;
            app.Slope41ratioSpinner.Limits = [0 8];
            app.Slope41ratioSpinner.ValueChangedFcn = createCallbackFcn(app, @Slope41ratioSpinnerValueChanged, true);
            app.Spinner_10 = uispinner(app.UIFigure);
            app.Spinner_10.Step = 0.1;
            app.Spinner_10.Limits = [0 8];
            app.Spinner_10.ValueChangedFcn = createCallbackFcn(app, @Spinner_10ValueChanged, true);
            app.Spinner_10.Value = 1;
            app.Spinner_20 = uispinner(app.UIFigure);
            app.Spinner_20.Step = 0.1;
            app.Spinner_20.Limits = [0 8];
            app.Spinner_20.ValueChangedFcn = createCallbackFcn(app, @Spinner_20ValueChanged, true);
            app.Spinner_20.Value = 1;
            app.Slope41ratioSpinnerLabel = uilabel(app.UIFigure);
            app.Slope41ratioSpinnerLabel.Text = 'Slope 4 (1/ratio)';
            app.Spinner_10Label = uilabel(app.UIFigure);
            app.Spinner_10Label.Text = '';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {'1', '2', '3'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_3ch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end